classdef proyectodos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        text1        matlab.ui.control.Label
        edit1        matlab.ui.control.EditField
        popupmenu2   matlab.ui.control.DropDown
        text2        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        popupmenu1   matlab.ui.control.DropDown
        text6        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        popupmenu3   matlab.ui.control.DropDown
        text9        matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function grafica_signals_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to grafica_signals (see VARARGIN)
            
            % Choose default command line output for grafica_signals
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            n_bits = get(handles.edit1,'String');
            
            if str2double(n_bits) > 0
            
                t_signal = get(handles.popupmenu1,'Value');
                nb = get(handles.popupmenu2,'Value');
                if nb == 1
            
                    nbts = 8;
                 elseif nb == 2
                    nbts = 16;
                 else
                    nbts = 32;
                end
            
                if t_signal == 1
            
                    bits = generador_bits(str2double(n_bits));
            
                    %Desfase OPSK
            
                    P1=pi/4; % 45 Grados
            
                    P2=0.75*pi; % 135 Grados
            
                    P3=1.25*pi; % 225 Grados
            
                    P4=1.75*pi; % 315 Grados
            
                    %Definicion de Frecuencias y periodos
            
                    f=1;
            
                    fs=100;
            
                    t1=0:1/fs:2; % Periodo para la senal QPSK
            
                    %Definicion de variable a usar
            
                    tiempo1=[];
            
                    QPSK=[];
            
            
                    %Senal QPSK
            
                    for ii=1:2: length(bits)
            
                    % Caso 1 = 00 Desfase de 45
            
                    if bits(ii)==0 && bits(ii+1)==0
            
                    bits00= sin(2*pi*f*t1 + P1);
            
                    QPSK=[QPSK (bits00)];
            
                    end
            
                    % Caso 2 = 10 Desfase de 135
            
                    if bits(ii)==1 && bits(ii+1)==0
            
                    bits10= sin(2*pi*f*t1 +P2);
            
                    QPSK=[QPSK (bits10)];
            
                    end
            
                    % Caso 3 = 10 Desfase de 225
            
                    if bits(ii)==1 && bits(ii+1)==1
            
                    bits11= sin(2*pi*f*t1 +P3);
            
                    QPSK=[QPSK (bits11)];
            
                    end
            
                    % Caso 4 = 10 Desfase de 315
            
                    if bits(ii)==0 && bits(ii+1)==1
            
                    bits01= sin(2*pi*f*t1 +P4);
            
                    QPSK=[QPSK (bits01)];
            
                    end
            
                    tiempo1= [tiempo1 t1];
            
                    t1=t1+2;
            
                    end
            
                    % Senal Digital
            
                    e = 10;
                    k = log2(nb);
                    snr = e+10*log10(k)-10*log10(1);
                    ruido = get(handles.popupmenu3,'Value');
                    signal_ruido = [];
            
                    if ruido == 1
                        signal_ruido = awgn(QPSK,snr,'measured');
                    elseif ruido == 2
                        signal_ruido = imnoise(QPSK,'speckle', 0.02);
                    elseif ruido == 3
                        signal_ruido = imnoise(QPSK,'gaussian', 0.02);
                    else
                        signal_ruido = imnoise(QPSK,'salt & pepper', 0.02);
                    end
            
                    espectro = log(abs(fft2(double(signal_ruido))));
                    axes(handles.axes2)
                    plot(tiempo1,espectro)
                    title('Senal QPSK+ruidos Espectro');
                    grid on;
                    axes(handles.axes3)
                    plot(QPSK);
                    title('Tiempo');
                    [ber,ser] = berawgn(0:str2double(n_bits),'pam',8);
                    axes(handles.axes1)
                    plot(ber)
                    title('Ber')
            
                elseif t_signal == 2
            
                    bits = generador_bits(str2double(n_bits));
                    spsk = psk(bits,nbts);
                    e = 10;
                    k = log2(nb);
                    snr = e+10*log10(k)-10*log10(1);
            
                    ruido = get(handles.popupmenu3,'Value');
                    signal_ruido = [];
            
                    if ruido == 1
                        signal_ruido = awgn(spsk,snr,'measured');
                    elseif ruido == 2
                        signal_ruido = imnoise(spsk,'speckle', 0.02);
                    elseif ruido == 3
                        signal_ruido = imnoise(spsk,'gaussian', 0.02);
                    else
                        signal_ruido = imnoise(spsk,'salt & pepper', 0.02);
                    end
            
                    espectro = log(abs(fft2(double(signal_ruido))));
                    axes(handles.axes2)
                    plot(espectro)
                    title('Senal PSK+ruidos Espectro');
                    grid on;
                    axes(handles.axes3)
                    plot(spsk);
                    title('Tiempo');
                    [ber,ser] = berawgn(0:str2double(n_bits),'pam',8);
                    axes(handles.axes1)
                    plot(ber)
                    title('Ber')
                else
            
                    bits = generador_bits(str2double(n_bits));
                    sqam = qammod(bits,nbts);
            
                    e = 10;
                    k = log2(nb);
                    snr = e+10*log10(k)-10*log10(1);
                    ruido = get(handles.popupmenu3,'Value');
                    signal_ruido = [];
            
                    if ruido == 1
                        signal_ruido = awgn(sqam,snr,'measured');
                    elseif ruido == 2
                        signal_ruido = imnoise(sqam,'speckle', 0.02);
                    elseif ruido == 3
                        signal_ruido = imnoise(sqam,'gaussian', 0.02);
                    else
                        signal_ruido = imnoise(sqam,'salt & pepper', 0.02);
                    end
            
                    espectro = log(abs(fft2(double(signal_ruido))));
                    axes(handles.axes2)
                    plot(espectro)
                    title('Senal QAM+ruidos Espectro');
                    grid on;
                    axes(handles.axes3)
                    plot(sqam);
                    title('Tiempo');
                    [ber,ser] = berawgn(0:str2double(n_bits),'pam',8);
                    axes(handles.axes1)
                    plot(ber)
                    title('Ber')
                end
            
            else
            
            
                msgbox('Verifique el numero de bits','Error en datos')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'grafica_signals';
            app.figure1.Tag = 'figure1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Graficar';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Proyecto 2° corte';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '0';
            app.popupmenu2 = uidropdown(app.figure1);
            app.popupmenu2.Items = {'8', '16', '32'};
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Value = '8';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Datos';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Señal';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'QSPK', 'PSK', 'QAM'};
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'QSPK';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Espectro';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ber';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Tiempo';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'AWGN', 'SPECKLE', 'SAL&PIMIENTA'};
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Value = 'AWGN';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Ruido';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectodos(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)grafica_signals_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end